classdef appPBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SignalGenerationTab            matlab.ui.container.Tab
        UIAxesInput                    matlab.ui.control.UIAxes
        UIAxesInputFT                  matlab.ui.control.UIAxes
        UIAxesTri                      matlab.ui.control.UIAxes
        UIAxesTriFT                    matlab.ui.control.UIAxes
        ADDButton                      matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        DisplayModeDropDown_2Label     matlab.ui.control.Label
        DisplayModeDropDown_2          matlab.ui.control.DropDown
        INPUTSIGNALPanel_2             matlab.ui.container.Panel
        AmplitudeVEditFieldLabel       matlab.ui.control.Label
        AmplitudeVEditField            matlab.ui.control.NumericEditField
        freqHzEditFieldLabel           matlab.ui.control.Label
        freqHzEditField                matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        InputSignalDropDownLabel       matlab.ui.control.Label
        InputSignalDropDown            matlab.ui.control.DropDown
        freq2HzEditFieldLabel          matlab.ui.control.Label
        freq2HzEditField               matlab.ui.control.NumericEditField
        varEditFieldLabel              matlab.ui.control.Label
        varEditField                   matlab.ui.control.NumericEditField
        wavFileNameEditFieldLabel      matlab.ui.control.Label
        wavFileNameEditField           matlab.ui.control.EditField
        TRIANGULARSIGNALPanel          matlab.ui.container.Panel
        TriangularfreqHzLabel          matlab.ui.control.Label
        TriFreq                        matlab.ui.control.NumericEditField
        TriangularAmplitudeVLabel      matlab.ui.control.Label
        TriAmplitudeEditField          matlab.ui.control.NumericEditField
        SimulationTab                  matlab.ui.container.Tab
        UIAxesInFT                     matlab.ui.control.UIAxes
        UIAxesTrFT                     matlab.ui.control.UIAxes
        UIAxesCompFT                   matlab.ui.control.UIAxes
        UIAxesOutFT                    matlab.ui.control.UIAxes
        UIAxesIn                       matlab.ui.control.UIAxes
        UIAxesTr                       matlab.ui.control.UIAxes
        UIAxesComp                     matlab.ui.control.UIAxes
        UIAxesOut                      matlab.ui.control.UIAxes
        simRUNButton                   matlab.ui.control.Button
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        CEditField                     matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        DisplayModeDropDownLabel       matlab.ui.control.Label
        DisplayModeDropDown            matlab.ui.control.DropDown
        PlayInputSignalButton          matlab.ui.control.Button
        PlayOutputSignalButton         matlab.ui.control.Button
        CutofffrequencyEditFieldLabel  matlab.ui.control.Label
        CutofffrequencyEditField       matlab.ui.control.NumericEditField
        FTzoomSwitchLabel              matlab.ui.control.Label
        FTzoomSwitch                   matlab.ui.control.Switch
        MeasurementTab                 matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        measureRUNButton               matlab.ui.control.Button
        THDLabel                       matlab.ui.control.Label
        GainLabel                      matlab.ui.control.Label
        FrequencyResponseModeDropDownLabel  matlab.ui.control.Label
        FrequencyResponseModeDropDown  matlab.ui.control.DropDown
        ResolutionnumberofsamplesEditFieldLabel  matlab.ui.control.Label
        ResolutionnumberofsamplesEditField  matlab.ui.control.NumericEditField
        FinishedLampLabel              matlab.ui.control.Label
        FinishedLamp                   matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        x = 0;
        X
        t        
        f
        fs
        D
        F
        Fs_wav
        tr
        TR
        factor
        wavToPlay
        originalToPlay
    end
    
    methods (Access = private)
        % The function are called with the parameters A, fo and t
        % A is the value of the amplitude, fo is the frequence of the signal
        % t is the time vector, it has implicit in it the sample frequence
        % and the duration of the signal
        
        function serra = dent_serra(~,A,fo,t) % Sawtooth funtion with frequence F
            serra = 2*(A*mod(fo*t,1)-A/2);
        end
        
        function tr = triangular(app,A,fo,t) % Triangular wave
                tr = 2*abs(dent_serra(app,A,fo,t))-A;
        end
        
        function s = sinusoide(~,A,fo,t) % Sinus wave
                s = A*sin(2*pi*t*fo);
        end
    
        function tren = polsos(app,A,fo,t) % Pulse wave
            a = sinusoide(app,A,fo,t);
            for i = 1: length(a)
                if(a(i)>0)
                    a(i) = 1;
                else
                    a(i)=-1;
                end
            end
            tren = a;
        end
            
        function s = soroll(~,var,t,n) % Guassian noise
            s = var.*randn(n,length(t));
        end
            
        function c = chirp(~,f1,f2,A,t) % Chirp signal
            frec = linspace(f1,f2,length(t));
            c = A*sin(2*pi*frec.*t);
        end
    
        function c = comparador(~,s1,s2) % Compares two signals, 
            l = s2;
            for i = 1:length(s1)
                if(s1(i)>s2(i)) % 1 when s1 is greater than s2
                    l(i) = 1;
                else            % -1 otherwise
                    l(i) = -1;  
                end    
            end
            c = l;
        end
        
        function senyal = power_amplifier(~,G,s) 
            senyal = G*s;   % Multiplies the s signal by a G factor
        end
    
        function senyal = lpf(~,s,R,L,C,fs)     % RLC Low pass filter
            LC = L*C;
            RC = R*C;
            b  = [1/LC 2/LC 1/LC];
            a = [(4*fs^2+2*fs/RC+1/LC) (2/LC-8*fs^2) (4*fs^2-2*fs/RC+1/LC)];
            senyal = filter(b,a,s);
        end
        
        function THD = thd(~,d) %Measures the THD of the d signal
            td = abs(fft(d));
            len = floor(length(td)/2);
            
            % Finds the first maximum and where is it located
            [a0,f0] = max(td(1:floor(len/2))); 
            num_har = floor(len / f0); % Calculates the number of harmonics the FFT will have
            p_har = 0; %Harmonics power
            
            % Finds the power of every harmonic and adds them together
            for i = 2:num_har      
                [ai,fi] = max( td( floor( (i) * f0 - 5 ) : floor( (i) * f0 + 5 ) ) );
                p_har = p_har + ai^2;
            end
            
            THD = sqrt(p_har)/a0; %THD calculation
        end
        
        function g = guany(~,x,y) % Gets the gain of the system (x-input, y-output) 
            px = sum(x.*x);
            py = sum(y.*y);
            g = sqrt(py/px);
        end
        
        function fr = noise_freq_response(app,R,L,C)
            D = 0.0001;
            fs = 192000;
            t = 0:(1/fs):D-(1/fs);
            
            num = 1000;
            promigy = 0;
            promigx = 0;
            
            t2 = 0:(1/fs):D*num-(1/fs);
      
            for i = 1:num
                soroll = app.soroll(1,t2,1);
                soroll_filtrat = app.lpf(soroll,R,L,C,fs);
                
                FTx = abs(fft(soroll(1+fs*D*(i-1):fs*D*i)));
                FTy = abs(fft(soroll_filtrat(1+fs*D*(i-1):fs*D*i)));

                promigy = promigy + FTy;
                promigx = promigx + FTx;
            end
            
            f = 0:1/D:fs/2-1/D;
            
            prom = sum(promigx)/length(promigx);
            fr = promigy/prom;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            % Activates when the 'ADD' button is pressed
            % Generates a signal acording the chosen parameters 
            % and shows it
            opt = app.InputSignalDropDown.Value; % type of signal
            A = app.AmplitudeVEditField.Value; % amplitude
            app.f = app.freqHzEditField.Value;
            f2 = app.freq2HzEditField.Value;
            app.D = app.DurationsEditField.Value;
            var = app.varEditField.Value;
            fo = app.TriFreq.Value;
            triA = app.TriAmplitudeEditField.Value;
            N = 32;
            app.fs = (2*N)*fo;
            app.t = 0:(1/app.fs):app.D-(1/app.fs);
            n = 0:1:app.D*app.fs-(1/app.fs);
        
            switch opt
                case '1'
                    app.x = app.x + sinusoide(app,A,app.f,app.t);
                  
                case '2'
                    app.x = app.x + dent_serra(app,A,app.f,app.t);
                    
                case '3'
                    app.x = app.x + triangular(app,A,app.f,app.t);
                    
                case '4'
                    app.x = app.x + polsos(app,A,app.f,app.t);
                    
                case '5'
                    app.x = app.x + soroll(app,var,app.t,1);
                    
                case '6'
                    app.x = app.x + chirp(app,app.f,f2,A,app.t);
            end
            
                plot(app.UIAxesInput, app.t, app.x);
                X0 = fft(app.x);
                app.X = X0(1:round(app.D*app.fs/2));
                app.F = 0:1/round(app.fs*app.D):1/2-1/round(app.fs*app.D);
                %plot(app.UIAxesInputFT, app.F, abs(app.X));
                plot(app.UIAxesInputFT, app.F*app.fs, abs(app.X)/2/length(abs(app.X)));
                app.UIAxesInputFT.XLim = [0 app.fs/2];
                
                app.tr = triangular(app,triA,fo,app.t);
                TR0 = abs(fft(app.tr));
                app.TR = TR0(1:round(app.D*app.fs/2));
                plot(app.UIAxesTri, app.t, app.tr); 
                %plot(app.UIAxesTriFT, app.F, app.TR);
                plot(app.UIAxesTriFT, app.F*app.fs, app.TR/2/length(app.TR));
                app.UIAxesTriFT.XLim = [0 app.fs/2];
                z = imresize(app.x,192000/app.fs);
                app.originalToPlay = z;
                
                app.DurationsEditField.Editable = false;
                app.TriFreq.Editable = false;
                app.TriAmplitudeEditField.Editable = false;
        end

        % Value changed function: InputSignalDropDown
        function InputSignalDropDownValueChanged(app, event)
            value = app.InputSignalDropDown.Value;
            % Selects the wanted type of signal and let the user choose the
            % required parameters for each kind
            switch value
                case '5'
                    % Noise signal parameters
                    % ( variance (var) and amplitude (A) )
                    app.varEditField.Editable = true;
                    app.varEditField.Enable = true;
                    app.varEditField.Visible = true;
                    app.varEditFieldLabel.Visible = true;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = false;
                    app.freqHzEditField.Enable = false;
                    app.freqHzEditField.Visible = false;
                    app.freqHzEditFieldLabel.Visible = false;
                    app.DurationsEditField.Editable = true;
                    app.DurationsEditField.Enable = true;
                    app.DurationsEditField.Visible = true;
                    app.DurationsEditFieldLabel.Visible = true;
                    app.AmplitudeVEditField.Editable = true;
                    app.AmplitudeVEditField.Enable = true;
                    app.AmplitudeVEditField.Visible = true;
                    app.AmplitudeVEditFieldLabel.Visible = true;
                    app.wavFileNameEditField.Editable = false;
                    app.wavFileNameEditField.Enable = false;
                    app.wavFileNameEditField.Visible = false;
                    app.wavFileNameEditFieldLabel.Visible = false;
                    app.RUNButton.Visible = false;
                    app.RUNButton.Enable = false;
                    app.ADDButton.Visible = true;
                    app.ADDButton.Enable = true;
                    app.RESETButton.Visible = true;
                    app.RESETButton.Enable = true;
                    app.DisplayModeDropDown_2.Enable = true;
                    app.DisplayModeDropDown.Enable = true;
                    
                case '6'
                    % Chirp signal
                    % The user gets to choose the amplitud and duration
                    % And also the starting and ending frequence
                    %              (freqHz)     (freq2Hz)   
                    app.freq2HzEditField.Editable = true;
                    app.freq2HzEditField.Enable = true;
                    app.freq2HzEditField.Visible = true;
                    app.freq2HzEditFieldLabel.Visible = true;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                    app.DurationsEditField.Editable = true;
                    app.DurationsEditField.Enable = true;
                    app.DurationsEditField.Visible = true;
                    app.DurationsEditFieldLabel.Visible = true;
                    app.AmplitudeVEditField.Editable = true;
                    app.AmplitudeVEditField.Enable = true;
                    app.AmplitudeVEditField.Visible = true;
                    app.AmplitudeVEditFieldLabel.Visible = true;
                    app.wavFileNameEditField.Editable = false;
                    app.wavFileNameEditField.Enable = false;
                    app.wavFileNameEditField.Visible = false;
                    app.wavFileNameEditFieldLabel.Visible = false;
                    app.RUNButton.Visible = false;
                    app.RUNButton.Enable = false;
                    app.ADDButton.Visible = true;
                    app.ADDButton.Enable = true;
                    app.RESETButton.Visible = true;
                    app.RESETButton.Enable = true;
                    app.DisplayModeDropDown_2.Enable = true;
                    app.DisplayModeDropDown.Enable = true;
                    
                case '7'
                    % .wav file
                    % The user can load a .wav file
                    % This option doesn't let the user add more signals
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = false;
                    app.freqHzEditField.Enable = false;
                    app.freqHzEditField.Visible = false;
                    app.freqHzEditFieldLabel.Visible = false;
                    app.DurationsEditField.Editable = false;
                    app.DurationsEditField.Enable = false;
                    app.DurationsEditField.Visible = false;
                    app.DurationsEditFieldLabel.Visible = false;
                    app.AmplitudeVEditField.Editable = false;
                    app.AmplitudeVEditField.Enable = false;
                    app.AmplitudeVEditField.Visible = false;
                    app.AmplitudeVEditFieldLabel.Visible = false;
                    app.wavFileNameEditField.Editable = true;
                    app.wavFileNameEditField.Enable = true;
                    app.wavFileNameEditField.Visible = true;
                    app.wavFileNameEditFieldLabel.Visible = true;
                    app.RUNButton.Visible = true;
                    app.RUNButton.Enable = true;
                    app.ADDButton.Visible = false;
                    app.ADDButton.Enable = false;
                    app.RESETButton.Visible = false;
                    app.RESETButton.Enable = false;
                    app.DisplayModeDropDown_2.Enable = false;
                    app.DisplayModeDropDown.Enable = false;
                otherwise
                    %Any other signal
                    %
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
                    app.DurationsEditField.Editable = true;
                    app.DurationsEditField.Enable = true;
                    app.DurationsEditField.Visible = true;
                    app.DurationsEditFieldLabel.Visible = true;
                    app.AmplitudeVEditField.Editable = true;
                    app.AmplitudeVEditField.Enable = true;
                    app.AmplitudeVEditField.Visible = true;
                    app.AmplitudeVEditFieldLabel.Visible = true;
                    app.wavFileNameEditField.Editable = false;
                    app.wavFileNameEditField.Enable = false;
                    app.wavFileNameEditField.Visible = false;
                    app.wavFileNameEditFieldLabel.Visible = false;
                    app.RUNButton.Visible = false;
                    app.RUNButton.Enable = false;
                    app.ADDButton.Visible = true;
                    app.ADDButton.Enable = true;
                    app.RESETButton.Visible = true;
                    app.RESETButton.Enable = true;
                    app.DisplayModeDropDown_2.Enable = true;
                    app.DisplayModeDropDown.Enable = true;
            end
        end

        % Button pushed function: simRUNButton
        function simRUNButtonPushed(app, event)
            if app.x == 0
                return
            end
            
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            R = app.REditField.Value;
            
            if app.InputSignalDropDown.Value == '7'
                y = app.x;
                Y = app.X;
                Fs = app.Fs_wav;
                
                Dwav = size(y,1)/Fs;
                twav = 0:1/Fs:Dwav-1/Fs;   
                Fwav = 0:1/(Fs*Dwav):1/2-1/(Fs*Dwav);
                
                plot(app.UIAxesIn, twav, y);
                plot(app.UIAxesInFT, Fwav*Fs, Y/2/length(Y));
%                 app.UIAxesInFT.XLim = [0 Fs/2];  
                
                plot(app.UIAxesTr,twav,app.tr);                
                plot(app.UIAxesTrFT, Fwav*Fs, app.TR/2/length(app.TR));
%                 app.UIAxesTrFT.XLim = [0 Fs/2];
                
%                 c = comparador(app,y,app.tr);
%                 C0 = fft(c);
%                 C = C0(1:round(length(C0)/2));
%                 plot(app.UIAxesComp, twav, c);
%                 plot(app.UIAxesCompFT, Fwav*Fs, abs(C)/2/length(abs(C)));
%                 app.UIAxesCompFT.XLim = [0 Fs/2];

                pwm = comparador(app,y,app.tr);
                PWM = fft(pwm);
                PWM = PWM(1:round(length(PWM)/2));
                PWM = abs(PWM);
                PWM = PWM/2/length(PWM);
                maxPWM = max(PWM);
                plot(app.UIAxesComp, twav, pwm);
                
                fr = app.noise_freq_response(R,L,C);
                Ffr = 0:1/0.0001:192000/2-1/0.0001;
                Ifr = 1:length(Ffr);
                
                cla(app.UIAxesCompFT);
                hold(app.UIAxesCompFT,'on');
                
                plot(app.UIAxesCompFT, Fwav*Fs, PWM);
                plot(app.UIAxesCompFT,Ffr,maxPWM*fr(Ifr));
                app.UIAxesCompFT.YLim = [0 1.1*maxPWM*max(fr)];
                
                hold(app.UIAxesCompFT,'off');
                
                G = app.GainEditField.Value;
                amp = power_amplifier(app,G,pwm);
    
                z = lpf(app,amp,R,L,C,Fs);
                
                Z0 = abs(fft(z));
                Z = Z0(1:round(Dwav*Fs/2));
                
                plot(app.UIAxesOut, twav, z);
                plot(app.UIAxesOutFT, Fwav*Fs, Z/2/length(Z));
%                 app.UIAxesOutFT.XLim = [0 Fs/2];
                app.FTzoomSwitchValueChanged;

                z2 = imresize(z,1/app.factor);
                
                Fs = Fs/app.factor;
                Dwav = size(z2,1)/Fs;
                twav = 0:1/Fs:Dwav-1/Fs;   
                
                Z0 = abs(fft(z2));
                Z = Z0(1:round(Dwav*Fs/2));
                Fwav = 0:1/(Fs*Dwav):1/2-1/(Fs*Dwav);
                
                app.wavToPlay = z2;
   
            else
                plot(app.UIAxesIn, app.t, app.x);
                plot(app.UIAxesInFT, app.F*app.fs, abs(app.X)/2/length(abs(app.X)));
                
                plot(app.UIAxesTr,app.t,app.tr);
                plot(app.UIAxesTrFT, app.F*app.fs, abs(app.TR)/2/length(abs(app.TR)));
                
                pwm = max(app.x)*comparador(app,app.x,app.tr);
                PWM = fft(pwm);
                PWM = PWM(1:round(app.D*app.fs/2));
                PWM = abs(PWM);
                PWM = PWM/2/length(PWM);
                maxPWM = max(PWM);
                plot(app.UIAxesComp, app.t, pwm);
                
                fr = app.noise_freq_response(R,L,C);
                Ffr = 0:1/0.0001:192000/2-1/0.0001;
                Ifr = 1:length(Ffr);
                
                cla(app.UIAxesCompFT);
                hold(app.UIAxesCompFT,'on');
                
                plot(app.UIAxesCompFT, app.F*app.fs, PWM);
                plot(app.UIAxesCompFT,Ffr,maxPWM*fr(Ifr));
                app.UIAxesCompFT.YLim = [0 1.1*maxPWM*max(fr)];
                
                hold(app.UIAxesCompFT,'off');
           
                G = app.GainEditField.Value;
                amp = power_amplifier(app,G,pwm);

                y = lpf(app,amp,R,L,C,app.fs);
                Y0 = fft(y);
                Y = Y0(1:round(app.D*app.fs/2));
                plot(app.UIAxesOut, app.t, y);
                plot(app.UIAxesOutFT, app.F*app.fs, abs(Y)/2/length(abs(Y)));
                app.FTzoomSwitchValueChanged;
                
                z = imresize(y,192000/app.fs);
                app.wavToPlay = z;
            end
            
        end

        % Button pushed function: measureRUNButton
        function measureRUNButtonPushed(app, event)
            %returns the frecuency response finished indicator to red
            app.FinishedLamp.Color = [1 0 0];
            % thd and gain
            Fs           = 44100;   
            duration     = 10;      
            Nbits        = 16;
            
            F_thd = 1000;                        
            t_thd = 0:(1/Fs):duration-(1/Fs);

            y  = 0.02*sin(2*pi*F_thd*t_thd);
            % Generates a pure sinusoid, f = 1000 Hz            
            player = audioplayer(y, Fs, Nbits);
            recorder = audiorecorder(Fs, Nbits, 1);
            record(recorder);
            playblocking(player);
            stop(recorder);
            signal = getaudiodata(recorder, 'single');
            

%             figure(1);
%             plot(t_thd,y);
%             figure(2);
%             plot(0:(1/Fs):length(signal)/Fs-(1/Fs),signal);
            
            len = duration*0.8*Fs;
            
            G = app.guany(y(1:len),signal(duration*0.1*Fs:duration*0.1*Fs+len-1));
            THD = app.thd(signal);
            
            app.GainLabel.Text = "Gain: " + G;
            app.THDLabel.Text = "THD: " + THD;
            
            % freq response
            
            fs_fr = 44100;
            D_fr = 2;
            A_fr = 0.02;
            t_fr = 0:(1/fs_fr):D_fr-(1/fs_fr);
            len_fr = 0.75*D_fr*fs_fr;
            N = app.ResolutionnumberofsamplesEditField.Value;
            f1 = 1.3;
            if app.FrequencyResponseModeDropDown.Value == '1'   
                mode = 1;
                player0 = audioplayer(A_fr*sin(2*pi*1000*t_fr),fs_fr,16);
                recorder0 = audiorecorder(fs_fr, 16, 1);
                record(recorder0);
                playblocking(player0);
                stop(recorder0);
                sortida0 =  getaudiodata(recorder0, 'single');
                sortida0 = sortida0(0.2*D_fr*fs_fr:0.2*D_fr*fs_fr+len_fr-1);
                potencia0 = sqrt(sum(sortida0.*sortida0)/fs_fr/D_fr/A_fr);                
                f2 = 5;            
            else
                mode = 2;
                f2 = log10(fs_fr/2);
                potencia0 = 1;
            end
            
            freq = logspace(f1,f2,N);
            
            guanys = zeros(1,N);
            senyal = zeros(mode,fs_fr*D_fr);
            
            for i = 1:N   
                senyal(1,:) = A_fr*sin(2*pi*freq(i)*t_fr);
                senyal(2,:) = senyal(1,:);
                player = audioplayer(senyal,fs_fr,16);
                recorder = audiorecorder(fs_fr, 16, mode);
                record(recorder);
                playblocking(player);
                stop(recorder);
                sortida = getaudiodata(recorder, 'single');
                enfinestrada = senyal(:,1:len_fr);
                sortida_enf = sortida(0.2*D_fr*fs_fr:0.2*D_fr*fs_fr+len_fr-1,:);
                if mode == 1
                    guany = app.guany(enfinestrada(1,:),sortida_enf(:,1));
                else
                    guany = app.guany(sortida_enf(:,2),sortida_enf(:,1));
                end

                guanys(i) = guany;
                         
                semilogx(app.UIAxes, freq, 20*log10(guanys/potencia0),'b');
                app.UIAxes.XLim = [20 100000];
                app.UIAxes.YLim = [-50 20];
            end
            
            semilogx(app.UIAxes, freq, 20*log10(guanys/potencia0));
            app.UIAxes.XLim = [20 100000];
            app.UIAxes.YLim = [-50 20];
            %changes the frecuency response finished indicator to green
            app.FinishedLamp.Color = [0 1 0];
        end

        % Value changed function: DisplayModeDropDown
        function DisplayModeDropDownValueChanged(app, event)
            value = app.DisplayModeDropDown.Value;
            switch value
                case '1'
                    app.UIAxesIn.XLim = [0 app.D];
                    app.UIAxesTr.XLim = [0 app.D];
                    app.UIAxesComp.XLim = [0 app.D];
                    app.UIAxesOut.XLim = [0 app.D];
                case '2'
                    app.UIAxesIn.XLim = [0 1/app.f];
                    app.UIAxesTr.XLim = [0 1/app.f];
                    app.UIAxesComp.XLim = [0 1/app.f];
                    app.UIAxesOut.XLim = [0 1/app.f];
                case '3'
                    app.UIAxesIn.XLim = [0 2/app.f];
                    app.UIAxesTr.XLim = [0 2/app.f];
                    app.UIAxesComp.XLim = [0 2/app.f];
                    app.UIAxesOut.XLim = [0 2/app.f];
            end
        end

        % Value changed function: DisplayModeDropDown_2
        function DisplayModeDropDown_2ValueChanged(app, event)
            value = app.DisplayModeDropDown_2.Value;
                switch value
                    case '1'
                        app.UIAxesInput.XLim = [0 app.D];
                        app.UIAxesTri.XLim = [0 app.D];
                    case '2'
                        app.UIAxesInput.XLim = [0 1/app.f];
                        app.UIAxesTri.XLim = [0 1/app.f];
                    case '3'
                        app.UIAxesInput.XLim = [0 2/app.f];
                        app.UIAxesTri.XLim = [0 2/app.f];
                end
        end

        % Button pushed function: PlayInputSignalButton
        function PlayInputSignalButtonPushed(app, event)
            if app.InputSignalDropDown.Value == '7'
                player = audioplayer(app.originalToPlay, app.Fs_wav/app.factor, 16);
            else 
                player = audioplayer(app.originalToPlay,192000,16);
            end
            playblocking(player);
        end

        % Button pushed function: PlayOutputSignalButton
        function PlayOutputSignalButtonPushed(app, event)
            if app.InputSignalDropDown.Value == '7'
                player = audioplayer(app.wavToPlay, app.Fs_wav/app.factor, 16);
            else
                player = audioplayer(app.wavToPlay,192000,16);
            end
            playblocking(player);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.x = 0;
            fo = app.TriFreq.Value;
            triA = app.TriAmplitudeEditField.Value;
            N = 8;
            [y,Fs] = audioread(app.wavFileNameEditField.Value);
            app.originalToPlay = y;
            Fs_d = (2*N)*fo;
            app.factor = Fs_d/Fs;
            y = imresize(y,app.factor);
            Fs = app.factor*Fs;
            
            Dwav = size(y,1)/Fs;
            twav = 0:1/Fs:Dwav-1/Fs;
            plot(app.UIAxesInput,twav,y);
            Y0 = abs(fft(y));
            Y = Y0(1:round(Dwav*Fs/2));
            Fwav = 0:1/(Fs*Dwav):1/2-1/(Fs*Dwav);
            plot(app.UIAxesInputFT,Fwav,Y);
            
            app.tr = triangular(app,triA,fo,twav);
            TR0 = abs(fft(app.tr));
            app.TR = TR0(1:round(Dwav*Fs/2));
            plot(app.UIAxesTri, twav, app.tr); 
            plot(app.UIAxesTriFT, Fwav, app.TR);
            
            app.x = y;
            app.X = Y;
            app.Fs_wav = Fs;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.x = 0;
            cla(app.UIAxesInput);
            cla(app.UIAxesInputFT);
            app.DurationsEditField.Editable = true;
            app.TriFreq.Editable = true;
            app.TriAmplitudeEditField.Editable = true;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            cutoff_freq = 1/(2*pi*sqrt(L*C));
            app.CutofffrequencyEditField.Value = cutoff_freq;
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            cutoff_freq = 1/(2*pi*sqrt(L*C));
            app.CutofffrequencyEditField.Value = cutoff_freq;
        end

        % Value changed function: FTzoomSwitch
        function FTzoomSwitchValueChanged(app, event)
            value = app.FTzoomSwitch.Value;
            if value == '0'
                if app.InputSignalDropDown.Value == '7'
                    app.UIAxesInFT.XLim = [0 app.Fs_wav/2];
                    app.UIAxesTrFT.XLim = [0 app.Fs_wav/2];
                    app.UIAxesCompFT.XLim = [0 app.Fs_wav/2];
                    app.UIAxesOutFT.XLim = [0 app.Fs_wav/2];
                else
                    app.UIAxesInFT.XLim = [0 app.fs/2];
                    app.UIAxesTrFT.XLim = [0 app.fs/2];
                    app.UIAxesCompFT.XLim = [0 app.fs/2];
                    app.UIAxesOutFT.XLim = [0 app.fs/2];
                end
            end
            if value == '1'
                app.UIAxesInFT.XLim = [0 3*app.TriFreq.Value];
                app.UIAxesTrFT.XLim = [0 3*app.TriFreq.Value];
                app.UIAxesCompFT.XLim = [0 3*app.TriFreq.Value];
                app.UIAxesOutFT.XLim = [0 3*app.TriFreq.Value];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalGenerationTab = uitab(app.TabGroup);
            app.SignalGenerationTab.Title = 'Signal Generation';
            app.UIAxesInput = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesInput, 'Input Signal')
            xlabel(app.UIAxesInput, 't(s)')
            ylabel(app.UIAxesInput, '')
            app.UIAxesInputFT = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesInputFT, 'F.T.')
            xlabel(app.UIAxesInputFT, 'f(Hz)')
            ylabel(app.UIAxesInputFT, '')
            app.UIAxesTri = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesTri, 'Triangular Signal')
            xlabel(app.UIAxesTri, 't(s)')
            ylabel(app.UIAxesTri, '')
            app.UIAxesTriFT = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesTriFT, 'F.T.')
            xlabel(app.UIAxesTriFT, 'f(Hz)')
            ylabel(app.UIAxesTriFT, '')
            app.ADDButton = uibutton(app.SignalGenerationTab, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.RESETButton = uibutton(app.SignalGenerationTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton = uibutton(app.SignalGenerationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Visible = 'off';
            app.RUNButton.Text = 'RUN';
            app.DisplayModeDropDown_2Label = uilabel(app.SignalGenerationTab);
            app.DisplayModeDropDown_2Label.Text = 'Display Mode';
            app.DisplayModeDropDown_2 = uidropdown(app.SignalGenerationTab);
            app.DisplayModeDropDown_2.Items = {'whole signal', 'one period', 'two periods'};
            app.DisplayModeDropDown_2.ItemsData = {'1', '2', '3'};
            app.DisplayModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayModeDropDown_2ValueChanged, true);
            app.DisplayModeDropDown_2.Value = '1';
            app.INPUTSIGNALPanel_2 = uipanel(app.SignalGenerationTab);
            app.INPUTSIGNALPanel_2.Title = 'INPUT SIGNAL';
            app.AmplitudeVEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.INPUTSIGNALPanel_2, 'numeric');
            app.AmplitudeVEditField.Limits = [0 Inf];
            app.AmplitudeVEditField.Value = 1;
            app.freqHzEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.freqHzEditFieldLabel.Text = 'freq (Hz)';
            app.freqHzEditField = uieditfield(app.INPUTSIGNALPanel_2, 'numeric');
            app.freqHzEditField.Limits = [0 Inf];
            app.freqHzEditField.Value = 20000;
            app.DurationsEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.INPUTSIGNALPanel_2, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.DurationsEditField.Value = 0.003;
            app.InputSignalDropDownLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.InputSignalDropDownLabel.Text = 'Input Signal';
            app.InputSignalDropDown = uidropdown(app.INPUTSIGNALPanel_2);
            app.InputSignalDropDown.Items = {'sinusoide', 'saw teeth', 'triangular', 'rectangular', 'noise', 'chirp', '.wav file'};
            app.InputSignalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.InputSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignalDropDownValueChanged, true);
            app.InputSignalDropDown.Value = '1';
            app.freq2HzEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.freq2HzEditFieldLabel.Visible = 'off';
            app.freq2HzEditFieldLabel.Text = 'freq2 (Hz)';
            app.freq2HzEditField = uieditfield(app.INPUTSIGNALPanel_2, 'numeric');
            app.freq2HzEditField.Limits = [0 Inf];
            app.freq2HzEditField.Editable = 'off';
            app.freq2HzEditField.Visible = 'off';
            app.freq2HzEditField.Value = 10;
            app.varEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.varEditFieldLabel.Visible = 'off';
            app.varEditFieldLabel.Text = 'var';
            app.varEditField = uieditfield(app.INPUTSIGNALPanel_2, 'numeric');
            app.varEditField.LowerLimitInclusive = 'off';
            app.varEditField.Limits = [0 Inf];
            app.varEditField.Editable = 'off';
            app.varEditField.Visible = 'off';
            app.varEditField.Value = 1;
            app.wavFileNameEditFieldLabel = uilabel(app.INPUTSIGNALPanel_2);
            app.wavFileNameEditFieldLabel.Visible = 'off';
            app.wavFileNameEditFieldLabel.Text = '.wav File Name';
            app.wavFileNameEditField = uieditfield(app.INPUTSIGNALPanel_2, 'text');
            app.wavFileNameEditField.Editable = 'off';
            app.wavFileNameEditField.Visible = 'off';
            app.wavFileNameEditField.Value = 'CantinaBand3.wav';
            app.TRIANGULARSIGNALPanel = uipanel(app.SignalGenerationTab);
            app.TRIANGULARSIGNALPanel.Title = 'TRIANGULAR SIGNAL';
            app.TriangularfreqHzLabel = uilabel(app.TRIANGULARSIGNALPanel);
            app.TriangularfreqHzLabel.Text = 'Triangular freq (Hz)';
            app.TriFreq = uieditfield(app.TRIANGULARSIGNALPanel, 'numeric');
            app.TriFreq.Limits = [0 Inf];
            app.TriFreq.Value = 500000;
            app.TriangularAmplitudeVLabel = uilabel(app.TRIANGULARSIGNALPanel);
            app.TriangularAmplitudeVLabel.Text = 'Triangular Amplitude (V)';
            app.TriAmplitudeEditField = uieditfield(app.TRIANGULARSIGNALPanel, 'numeric');
            app.TriAmplitudeEditField.Limits = [0 Inf];
            app.TriAmplitudeEditField.Value = 1.1;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxesInFT = uiaxes(app.SimulationTab);
            title(app.UIAxesInFT, 'Input Signal F.T.')
            xlabel(app.UIAxesInFT, 'f(Hz)')
            ylabel(app.UIAxesInFT, '')
            app.UIAxesTrFT = uiaxes(app.SimulationTab);
            title(app.UIAxesTrFT, 'Triangular Signal F.T.')
            xlabel(app.UIAxesTrFT, 'f(Hz)')
            ylabel(app.UIAxesTrFT, '')
            app.UIAxesCompFT = uiaxes(app.SimulationTab);
            title(app.UIAxesCompFT, 'PMW F.T.')
            xlabel(app.UIAxesCompFT, 'f(Hz)')
            ylabel(app.UIAxesCompFT, '')
            app.UIAxesOutFT = uiaxes(app.SimulationTab);
            title(app.UIAxesOutFT, 'Output Signal F.T.')
            xlabel(app.UIAxesOutFT, 'f(Hz)')
            ylabel(app.UIAxesOutFT, '')
            app.UIAxesIn = uiaxes(app.SimulationTab);
            title(app.UIAxesIn, 'Input Signal')
            xlabel(app.UIAxesIn, 't(s)')
            ylabel(app.UIAxesIn, '')
            app.UIAxesTr = uiaxes(app.SimulationTab);
            title(app.UIAxesTr, 'Triangular Signal')
            xlabel(app.UIAxesTr, 't(s)')
            ylabel(app.UIAxesTr, '')
            app.UIAxesComp = uiaxes(app.SimulationTab);
            title(app.UIAxesComp, 'PMW')
            xlabel(app.UIAxesComp, 't(s)')
            ylabel(app.UIAxesComp, '')
            app.UIAxesOut = uiaxes(app.SimulationTab);
            title(app.UIAxesOut, 'Output Signal')
            xlabel(app.UIAxesOut, 't(s)')
            ylabel(app.UIAxesOut, '')
            app.simRUNButton = uibutton(app.SimulationTab, 'push');
            app.simRUNButton.ButtonPushedFcn = createCallbackFcn(app, @simRUNButtonPushed, true);
            app.simRUNButton.Text = 'RUN';
            app.GainEditFieldLabel = uilabel(app.SimulationTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.SimulationTab, 'numeric');
            app.GainEditField.Limits = [0 Inf];
            app.GainEditField.Value = 5;
            app.LEditFieldLabel = uilabel(app.SimulationTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SimulationTab, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 2.2e-05;
            app.CEditFieldLabel = uilabel(app.SimulationTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.SimulationTab, 'numeric');
            app.CEditField.Limits = [0 Inf];
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.CEditField.Value = 4.7e-07;
            app.REditFieldLabel = uilabel(app.SimulationTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SimulationTab, 'numeric');
            app.REditField.Limits = [0 Inf];
            app.REditField.Value = 5;
            app.DisplayModeDropDownLabel = uilabel(app.SimulationTab);
            app.DisplayModeDropDownLabel.Text = 'Display Mode';
            app.DisplayModeDropDown = uidropdown(app.SimulationTab);
            app.DisplayModeDropDown.Items = {'whole signal', 'one period', 'two periods'};
            app.DisplayModeDropDown.ItemsData = {'1', '2', '3'};
            app.DisplayModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayModeDropDownValueChanged, true);
            app.DisplayModeDropDown.Value = '1';
            app.PlayInputSignalButton = uibutton(app.SimulationTab, 'push');
            app.PlayInputSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayInputSignalButtonPushed, true);
            app.PlayInputSignalButton.Text = 'Play Input Signal';
            app.PlayOutputSignalButton = uibutton(app.SimulationTab, 'push');
            app.PlayOutputSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputSignalButtonPushed, true);
            app.PlayOutputSignalButton.Text = 'Play Output Signal';
            app.CutofffrequencyEditFieldLabel = uilabel(app.SimulationTab);
            app.CutofffrequencyEditFieldLabel.Text = 'Cutoff frequency';
            app.CutofffrequencyEditField = uieditfield(app.SimulationTab, 'numeric');
            app.CutofffrequencyEditField.Editable = 'off';
            app.CutofffrequencyEditField.Value = 50000;
            app.FTzoomSwitchLabel = uilabel(app.SimulationTab);
            app.FTzoomSwitchLabel.Text = 'F.T. zoom';
            app.FTzoomSwitch = uiswitch(app.SimulationTab, 'slider');
            app.FTzoomSwitch.ItemsData = {'0', '1'};
            app.FTzoomSwitch.ValueChangedFcn = createCallbackFcn(app, @FTzoomSwitchValueChanged, true);
            app.FTzoomSwitch.Value = '0';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes = uiaxes(app.MeasurementTab);
            title(app.UIAxes, 'FREQUENCY RESPONSE')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.measureRUNButton = uibutton(app.MeasurementTab, 'push');
            app.measureRUNButton.ButtonPushedFcn = createCallbackFcn(app, @measureRUNButtonPushed, true);
            app.measureRUNButton.Text = 'RUN';
            app.THDLabel = uilabel(app.MeasurementTab);
            app.THDLabel.Text = 'THD:';
            app.GainLabel = uilabel(app.MeasurementTab);
            app.GainLabel.Text = 'Gain:';
            app.FrequencyResponseModeDropDownLabel = uilabel(app.MeasurementTab);
            app.FrequencyResponseModeDropDownLabel.Text = 'Frequency Response Mode';
            app.FrequencyResponseModeDropDown = uidropdown(app.MeasurementTab);
            app.FrequencyResponseModeDropDown.Items = {'Mono', 'Stereo'};
            app.FrequencyResponseModeDropDown.ItemsData = {'1', '2'};
            app.FrequencyResponseModeDropDown.Value = '1';
            app.ResolutionnumberofsamplesEditFieldLabel = uilabel(app.MeasurementTab);
            app.ResolutionnumberofsamplesEditFieldLabel.Text = 'Resolution (number of samples)';
            app.ResolutionnumberofsamplesEditField = uieditfield(app.MeasurementTab, 'numeric');
            app.ResolutionnumberofsamplesEditField.Limits = [10 10000];
            app.ResolutionnumberofsamplesEditField.RoundFractionalValues = 'on';
            app.ResolutionnumberofsamplesEditField.ValueDisplayFormat = '%.0f';
            app.ResolutionnumberofsamplesEditField.Value = 100;
            app.FinishedLampLabel = uilabel(app.MeasurementTab);
            app.FinishedLampLabel.Text = 'Finished?';
            app.FinishedLamp = uilamp(app.MeasurementTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end